classdef soundApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoundAppUIFigure               matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        WelcomeTab                     matlab.ui.container.Tab
        Image21                        matlab.ui.control.Image
        InfoTab                        matlab.ui.container.Tab
        PlaysandpausesthecurrenttrackLabel  matlab.ui.control.Label
        StopsthecurrentsessionLabel    matlab.ui.control.Label
        Mixesbetweentrack12Label       matlab.ui.control.Label
        ExitsthehandgesturerecognitionLabel  matlab.ui.control.Label
        EnablesDisablesthevolumeLabel  matlab.ui.control.Label
        RightHandupLabel               matlab.ui.control.Label
        RightHanddownLabel             matlab.ui.control.Label
        GeneralInformationLabel        matlab.ui.control.Label
        waveoutLabel                   matlab.ui.control.Label
        waveInLabel                    matlab.ui.control.Label
        fistLabel                      matlab.ui.control.Label
        openLabel                      matlab.ui.control.Label
        pinchLabel                     matlab.ui.control.Label
        UpvolumeLabel                  matlab.ui.control.Label
        DownvolumeLabel                matlab.ui.control.Label
        Image18                        matlab.ui.control.Image
        RunTab                         matlab.ui.container.Tab
        ReaperIPEditFieldLabel         matlab.ui.control.Label
        ReaperIPEditField              matlab.ui.control.EditField
        Step2CalibrationLabel          matlab.ui.control.Label
        Step1IPPortsetupLabel          matlab.ui.control.Label
        CalibrateButton                matlab.ui.control.Button
        Step3RunLabel                  matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        PlayPauseLampLabel             matlab.ui.control.Label
        PlayPauseLamp                  matlab.ui.control.Lamp
        StopLampLabel                  matlab.ui.control.Label
        StopLamp                       matlab.ui.control.Lamp
        MixLampLabel                   matlab.ui.control.Label
        MixLamp                        matlab.ui.control.Lamp
        ExitLampLabel                  matlab.ui.control.Label
        ExitLamp                       matlab.ui.control.Lamp
        VolumeLampLabel                matlab.ui.control.Label
        VolumeLamp                     matlab.ui.control.Lamp
        NogestureLampLabel             matlab.ui.control.Label
        NogestureLamp                  matlab.ui.control.Lamp
        PortEditFieldLabel             matlab.ui.control.Label
        PortEditField                  matlab.ui.control.EditField
        Track1LampLabel                matlab.ui.control.Label
        Track1Lamp                     matlab.ui.control.Lamp
        Track2LampLabel                matlab.ui.control.Label
        Track2Lamp                     matlab.ui.control.Lamp
        Image7                         matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        Image12                        matlab.ui.control.Image
        Image13                        matlab.ui.control.Image
        Image14                        matlab.ui.control.Image
        Image15                        matlab.ui.control.Image
        Image16                        matlab.ui.control.Image
        Image17                        matlab.ui.control.Image
        Button                         matlab.ui.control.Button
        Image22                        matlab.ui.control.Image
        Image23                        matlab.ui.control.Image
        Image24                        matlab.ui.control.Image
        ArmbandButtonGroup             matlab.ui.container.ButtonGroup
        MyoButton                      matlab.ui.control.RadioButton
        GForceButton                   matlab.ui.control.RadioButton
        UIAxesBracelet                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)        
        EV=0.25;
        order=4;
        umbral_high=0;
        umbral_low=0;
        ip_='0.0.0.0';
        port_=8005;
        runMYO=1;
        emg=0;
        imu=0; 
        oldworkspace;
        newworksapce;
        MyoDevice=1;
        GForceDevice=0;
        selectedDevice=1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

           app.PlayPauseLamp.Color='r';
           app.StopLamp.Color='r';
           app.NogestureLamp.Color='g';
           
           app.MixLamp.Color='r';           
           app.ExitLamp.Color='r';
           app.VolumeLamp.Color='r';

           app.UIAxesBracelet.XAxis.TickLabels = {};
           app.UIAxesBracelet.YAxis.TickLabels = {};
           % Display image and stretch to fill axes
           I = imshow('..\Project_EMG_Sound\Images\myo.png', 'Parent', app.UIAxesBracelet, 'XData', [1 app.UIAxesBracelet.Position(3)], 'YData', [1 app.UIAxesBracelet.Position(4)]);
           % Set limits of axes
           app.UIAxesBracelet.XLim = [0 I.XData(2)];
           app.UIAxesBracelet.YLim = [0 I.YData(2)];


        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            
            [app.order,app.umbral_high,app.umbral_low]=WMcalibration(app.EV,app.selectedDevice);
            beep; 

            variables.order=app.order;
            variables.umbral_high=app.umbral_high;
            variables.umbral_low=app.umbral_low;
            
            variables.setVol=0;
            variables.setVol_1=1;
            variables.setVol_2=0;
            variables.vol_main=0.7;
            variables.vol_1=0.7;
            variables.vol_2=0;           
            
            save('defaultValues.mat',"variables");
        end

        % Value changed function: ReaperIPEditField
        function ReaperIPEditFieldValueChanged(app, event)
            app.ip_ = app.ReaperIPEditField.Value;            
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            app.port_ = str2num(app.PortEditField.Value);            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            base=pwd;
            base=horzcat(base,'\defaultValues.mat');
            pb=load(base);
            
            
            Wmoos_init_parameters_(app.ip_,app.port_,pb.variables.vol_main,pb.variables.setVol,pb.variables.setVol_1,pb.variables.setVol_2,pb.variables.vol_1,pb.variables.vol_2);
            
            
            WMip=app.ip_;
            WMport=app.port_;
            
            
            if app.selectedDevice ==1
                % ============= MYO ===============
                countMyos=1;
                m = MyoMex(countMyos);
                m1 = m.myoData(1);
                m1.timeEMG;
                m1.emg;
                m1.stopStreaming();
                m.myoData.clearLogs();
                m1.startStreaming();
                % ==================================
            else
                bits=8;
                res=gForce_mex('setEmgResolution', bits);
                res=gForce_mex('setEmgSamplingRate', 200);
                
            end
            app.emg=zeros(40,8);
            
            y=0;
            assignin('base','y',y);
            app.order      =  evalin('base', 'order');
            high_limit =  evalin('base', 'umbral_high');
            low_limit  =  evalin('base', 'umbral_low');
            
            assignin('base','track_1',  1);
            assignin('base','track_2',  0);
            track_1  =  evalin('base', 'track_1');
            track_2  =  evalin('base', 'track_2');
            
            previous_=0;
            pp=0;
            ss=0;
            ss3=0;
            ss33=0;
            ss4=0;
            ss44=0;
            next_=0;
            ok_wave=0;
            app.runMYO=1;
            Wmoos_local_counter=1;
            Wmoos_response=0;
            qw=0;
            qx=0;
            qy=0;
            qz=0;
            imuData=0;         


            % ======================= Commands ======================
            % waveOut   = next
            % waveIn    = previous
            % fist      = exit
            % open      = pause/resume
            % pinch     = control mode on / control mode off
            % noGesture = no action
            % =======================================================
            %pause(1)
            
            while app.runMYO==1
                
                if app.selectedDevice==1
                    % ============= MYO ===============
                    timeEMG = m1.timeEMG_log;
                    timeIMU = m1.timeIMU_log;
                    
                    
                    if ~isempty(timeEMG)
                        
                        T_emg=timeEMG(:,1)>=(timeEMG(end,1) - 0.8 );
                        app.emg=m1.emg_log(T_emg,:);
                        umbral=Wsumenv(app.emg');
                        
                        
                        T_imu=timeIMU(:,1)>=(timeIMU(end,1)-0.8);
                        app.imu = m1.quat_log(T_imu,:);
                        matrix_quat=app.imu(end,:);
                        qw=matrix_quat(1,1);
                        qx=matrix_quat(1,2);
                        qy=matrix_quat(1,3);
                        qz=matrix_quat(1,4);
                        
                        if umbral(1:4,:) > 0.05*(high_limit) | umbral(5:8,:) > 0.05*(low_limit)
                            
                            app.emg=m1.emg_log(T_emg,WMoos_X(app.order));
                            
                            tic
                            [selected,scores]=Recognition_5waves(app.emg);
                            lapse_recog=toc;
                            [selected_mod_zero,~]  = Wmoos_correction_module_zero(scores,selected,lapse_recog);
                            selected_mod_one      = Wmoos_correction_module_one(selected_mod_zero);
                            [  ~ ,com,two_counter] = Wmoos_correction_module_two(selected_mod_one,4);
                            
                            
                            emgCounterCompact   =  evalin('base', 'emgCounterCompact');
                            check_compact=emgCounterCompact-1;
                            pause(0.05);
                            if check_compact==0 && two_counter==5
                                
                                
                                TargetPrediction_fixed_up  =  com;
                                TargetPredictionHorzcat  = evalin('base', 'TargetPredictionHorzcat');
                                TargetPredictionHorzcat  = horzcat(TargetPredictionHorzcat,TargetPrediction_fixed_up);
                                assignin('base','TargetPredictionHorzcat', TargetPredictionHorzcat);
                                
                                if Wmoos_local_counter==1
                                    
                                    TargetPredictionHorzcat  =  evalin('base', 'TargetPredictionHorzcat');
                                    TargetPredictionHorzcat  =  TargetPredictionHorzcat(1,2:end);
                                    
                                    TargetPredictionUnion    =  emgCompactedTotal(TargetPredictionHorzcat,length (TargetPredictionHorzcat));
                                    Wmoos_response           =  Wmoos_summary_class(TargetPredictionUnion);
                                    
                                    clear TargetPredictionHorzcat TargetPredictionUnion
                                    TargetPredictionHorzcat{1,1}='';
                                    assignin('base','TargetPredictionHorzcat', TargetPredictionHorzcat);
                                    Wmoos_local_counter=0;
                                    
                                end
                                Wmoos_local_counter=Wmoos_local_counter+1;
                            else
                                Wmoos_response  = 'noGesture';
                            end
                        else
                            [selected_mod_zero,~]  = Wmoos_correction_module_zero(0,6,0);
                            Wmoos_correction_module_one(selected_mod_zero);
                            Wmoos_correction_module_two('noGesture',4);
                            Wmoos_response  = 'noGesture';
                        end
                    end
                    
                else
                    
                    % ============= GForce ===============
                    
                    %gForce_mex('clearEmg');
                    
                    pause(0.8)
                    app.emg = gForce_mex('getEmg');
                    app.emg = double(app.emg)*2;
                    mean_=mean(app.emg,2);
                    norm_=((2^(8+1))-1)*ones(8,1)- mean_;
                    app.emg=app.emg-mean_;
                    app.emg=app.emg./norm_;
                    
                    umbral=WMoos_F5(app.emg);                    
                    
                    if umbral(1:4,:) > 0.05*(high_limit) | umbral(5:8,:) > 0.05*(low_limit)

                        app.emg=(app.emg)';
                        app.emg=app.emg(:,WMoos_X(app.order));
                        Wmoos_response=Recognition_5waves(app.emg);
                        
                    else
                        Wmoos_response  = 'noGesture';
                    end
                    
                    
                end
                % ==========================================
                
                Wmoos_response
                
                %Wave=Wmoos_names_convertion_back(Wmoos_response);
                
                if app.selectedDevice==1
                    Wave=Wmoos_names_convertion_back(Wmoos_response);
                    % ============= MYO ===============
                    %============================= MyoIMU 1 =================================
                    
                    quat_roll  = atan2(2*(qw*qx + qy*qz),1- 2* (qx*qx + qy*qy));
                    quat_pitch = asin(max(-1, min(1,2 * (qw * qy - qz * qx))));
                    quat_yaw   = atan2(2*(qw * qz + qx * qy),1 - 2 * (qy * qy + qz * qz));
                    rpy=[quat_roll,quat_pitch,quat_yaw];
                    %=========================================================================
                else
                    Wave=Wmoos_response;
                    % ============= GFORCE ===============
                    %============================= IMU  =====================================
                    
                    wxyz=gForce_mex('getQuaternions');
                    wxyz=wxyz(:,1);
                    vals=wxyz';
                    qw=vals(1,1);
                    qx=vals(1,2);
                    qy=vals(1,3);
                    qz=vals(1,4);
                    
                    quat_roll  = atan2(2*(qw*qx + qy*qz),1- 2* (qx*qx + qy*qy));
                    quat_pitch = asin(max(-1, min(1,2 * (qw * qy - qz * qx))));
                    quat_yaw   = atan2(2*(qw * qz + qx * qy),1 - 2 * (qy * qy + qz * qz));
                    rpy=[quat_roll,quat_pitch,quat_yaw]
                    %========================================================================     
                    
                end
                
                
                xvol_1=evalin('base', 'setVol_1');
                xvol_2=evalin('base', 'setVol_2');
                
                if xvol_1==1
                    app.Track1Lamp.Color='g';
                    app.Track2Lamp.Color='r';
                else
                    app.Track1Lamp.Color='r';
                    app.Track2Lamp.Color='g';
                end
     
                
                %waveOut
                if Wave==1
                    %Play/pause
                    app.NogestureLamp.Color='r';
                    app.StopLamp.Color='r';
                    app.MixLamp.Color='r';
                    app.ExitLamp.Color='r';
                    app.VolumeLamp.Color='r';
                    app.PlayPauseLamp.Color='g';
                    remoteReaper(WMip,WMport,Wave,0,0);
                end
                %waveIn
                if Wave==2
                    %Stop
                    app.NogestureLamp.Color='r';
                    app.StopLamp.Color='g';
                    app.MixLamp.Color='r';
                    app.ExitLamp.Color='r';
                    app.VolumeLamp.Color='r';
                    app.PlayPauseLamp.Color='r';
                    remoteReaper(WMip,WMport,Wave,0,0);
                end
                
                %fist
                if Wave==3
                    %mix
                    app.NogestureLamp.Color='r';
                    app.StopLamp.Color='r';
                    app.MixLamp.Color='g';
                    app.ExitLamp.Color='r';
                    app.VolumeLamp.Color='r';
                    app.PlayPauseLamp.Color='r';
                    pause(1)
                    track_1  =  evalin('base', 'track_1');
                    track_2  =  evalin('base', 'track_2');
                    remoteReaper(WMip,WMport,Wave,track_1,track_2);
                end
                
                %open
                if Wave==4
                    %cycle/ exit program
                    app.NogestureLamp.Color='r';
                    app.StopLamp.Color='r';
                    app.MixLamp.Color='r';
                    app.ExitLamp.Color='g';
                    app.VolumeLamp.Color='r';
                    app.PlayPauseLamp.Color='r';
                    pause(1)
                    app.runMYO=0;
                    pause(1);
                    
                end
                
                if Wave==6
                    app.NogestureLamp.Color='g';
                end
                
                if Wave==5
                    
                    app.NogestureLamp.Color='r';
                    app.StopLamp.Color='r';
                    app.MixLamp.Color='r';
                    app.ExitLamp.Color='r';
                    app.VolumeLamp.Color='g';
                    app.PlayPauseLamp.Color='r';
                    
                    if ss==0
                        ok_wave=1;
                    end
                    pause(1)
                    if ss==1
                        ok_wave=0;
                    end
                    pause(1)
                    ss=ss+1;
                    if ss>=2
                        ss=0;
                    end
                end
                
                if ss==0
                    %volume disabled
                    app.VolumeLamp.Color='r';
                else
                    %volume enabled
                    app.NogestureLamp.Color='r';
                    app.StopLamp.Color='r';
                    app.MixLamp.Color='r';
                    app.ExitLamp.Color='r';
                    app.VolumeLamp.Color='g';
                    app.PlayPauseLamp.Color='r';
                    
                    % ============= MYO ===============
                    if app.selectedDevice==1
                        if quat_pitch>=0 && quat_pitch<=0.75
                            
                            remoteReaper(WMip,WMport,5,quat_pitch,0);
                        end
                        % ================================
                    else
                        
                    end
                end
            end
            
            
            app.NogestureLamp.Color='r';
            app.StopLamp.Color='r';
            app.MixLamp.Color='r';
            app.ExitLamp.Color='r';
            app.VolumeLamp.Color='r';
            app.PlayPauseLamp.Color='r';
            
            if app.selectedDevice==1
                % ============= MYO ===============
                m1.stopStreaming();    %Stop streaming Myo_1 all data
                m.myoData.clearLogs();
                m.delete;              %Delete variable for Myo_1
                clear m m1            %Clear variable for Myo_1
                % =================================
            else
                clear gForce_mex
            end
            
            
            variables.order=app.order;
            variables.umbral_high=app.umbral_high;
            variables.umbral_low=app.umbral_low;
            variables.setVol=1;
            variables.setVol_1=evalin('base', 'setVol_1');
            variables.setVol_2=evalin('base', 'setVol_2');
            variables.vol_1=evalin('base', 'vol_1');
            variables.vol_2=evalin('base', 'vol_2');
            variables.vol_main=evalin('base', 'vol_main');            
            save('defaultValues.mat',"variables");


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            f = uifigure('Name','Help');
            im = uiimage(f);
            im.ImageSource = 'Images\InfoHelp.png';
            im.Position=[30 -3 450 450];
          

        end

        % Selection changed function: ArmbandButtonGroup
        function ArmbandButtonGroupSelectionChanged(app, event)
            
            if app.MyoButton.Value==1
                app.selectedDevice=1;
                app.UIAxesBracelet.XAxis.TickLabels = {};
                app.UIAxesBracelet.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imshow('..\Project_EMG_Sound\Images\myo.png', 'Parent', app.UIAxesBracelet, 'XData', [1 app.UIAxesBracelet.Position(3)], 'YData', [1 app.UIAxesBracelet.Position(4)]);
                % Set limits of axes
                app.UIAxesBracelet.XLim = [0 I.XData(2)];
                app.UIAxesBracelet.YLim = [0 I.YData(2)];
                
            end
            if app.GForceButton.Value==1
                app.selectedDevice=2;
                app.UIAxesBracelet.XAxis.TickLabels = {};
                app.UIAxesBracelet.YAxis.TickLabels = {};
                % Display image and stretch to fill axes
                I = imshow('..\Project_EMG_Sound\Images\gforce.png', 'Parent', app.UIAxesBracelet, 'XData', [1 app.UIAxesBracelet.Position(3)], 'YData', [1 app.UIAxesBracelet.Position(4)]);
                % Set limits of axes
                app.UIAxesBracelet.XLim = [0 I.XData(2)];
                app.UIAxesBracelet.YLim = [0 I.YData(2)];
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoundAppUIFigure = uifigure('Visible', 'off');
            app.SoundAppUIFigure.Name = 'Sound App';
            app.TabGroup = uitabgroup(app.SoundAppUIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.Image21 = uiimage(app.WelcomeTab);
            app.Image21.ImageSource = 'welcome.png';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.PlaysandpausesthecurrenttrackLabel = uilabel(app.InfoTab);
            app.PlaysandpausesthecurrenttrackLabel.Text = 'Plays and pauses the current track';
            app.StopsthecurrentsessionLabel = uilabel(app.InfoTab);
            app.StopsthecurrentsessionLabel.Text = 'Stops the current session';
            app.Mixesbetweentrack12Label = uilabel(app.InfoTab);
            app.Mixesbetweentrack12Label.Text = ' Mixes between track 1 & 2';
            app.ExitsthehandgesturerecognitionLabel = uilabel(app.InfoTab);
            app.ExitsthehandgesturerecognitionLabel.Text = 'Exits the hand gesture recognition ';
            app.EnablesDisablesthevolumeLabel = uilabel(app.InfoTab);
            app.EnablesDisablesthevolumeLabel.Text = ' Enables/Disables the volume';
            app.RightHandupLabel = uilabel(app.InfoTab);
            app.RightHandupLabel.Text = ' Right Hand up';
            app.RightHanddownLabel = uilabel(app.InfoTab);
            app.RightHanddownLabel.Text = 'Right Hand down';
            app.GeneralInformationLabel = uilabel(app.InfoTab);
            app.GeneralInformationLabel.Text = 'General Information';
            app.waveoutLabel = uilabel(app.InfoTab);
            app.waveoutLabel.Text = 'wave out';
            app.waveInLabel = uilabel(app.InfoTab);
            app.waveInLabel.Text = '  wave In';
            app.fistLabel = uilabel(app.InfoTab);
            app.fistLabel.Text = '     fist';
            app.openLabel = uilabel(app.InfoTab);
            app.openLabel.Text = ' open';
            app.pinchLabel = uilabel(app.InfoTab);
            app.pinchLabel.Text = 'pinch';
            app.UpvolumeLabel = uilabel(app.InfoTab);
            app.UpvolumeLabel.Text = 'Up volume';
            app.DownvolumeLabel = uilabel(app.InfoTab);
            app.DownvolumeLabel.Text = 'Down volume';
            app.Image18 = uiimage(app.InfoTab);
            app.Image18.ImageSource = 'gestures-info.png';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.ReaperIPEditFieldLabel = uilabel(app.RunTab);
            app.ReaperIPEditFieldLabel.Text = 'Reaper IP';
            app.ReaperIPEditField = uieditfield(app.RunTab, 'text');
            app.ReaperIPEditField.ValueChangedFcn = createCallbackFcn(app, @ReaperIPEditFieldValueChanged, true);
            app.Step2CalibrationLabel = uilabel(app.RunTab);
            app.Step2CalibrationLabel.Text = 'Step 2: Calibration';
            app.Step1IPPortsetupLabel = uilabel(app.RunTab);
            app.Step1IPPortsetupLabel.Text = 'Step 1: IP & Port setup';
            app.CalibrateButton = uibutton(app.RunTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.Step3RunLabel = uilabel(app.RunTab);
            app.Step3RunLabel.Text = 'Step 3: Run';
            app.ConnectButton = uibutton(app.RunTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PlayPauseLampLabel = uilabel(app.RunTab);
            app.PlayPauseLampLabel.Text = 'Play/Pause';
            app.PlayPauseLamp = uilamp(app.RunTab);
            app.StopLampLabel = uilabel(app.RunTab);
            app.StopLampLabel.Text = 'Stop';
            app.StopLamp = uilamp(app.RunTab);
            app.MixLampLabel = uilabel(app.RunTab);
            app.MixLampLabel.Text = 'Mix';
            app.MixLamp = uilamp(app.RunTab);
            app.ExitLampLabel = uilabel(app.RunTab);
            app.ExitLampLabel.Text = 'Exit';
            app.ExitLamp = uilamp(app.RunTab);
            app.VolumeLampLabel = uilabel(app.RunTab);
            app.VolumeLampLabel.Text = 'Volume';
            app.VolumeLamp = uilamp(app.RunTab);
            app.NogestureLampLabel = uilabel(app.RunTab);
            app.NogestureLampLabel.Text = 'No gesture';
            app.NogestureLamp = uilamp(app.RunTab);
            app.PortEditFieldLabel = uilabel(app.RunTab);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.RunTab, 'text');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.Track1LampLabel = uilabel(app.RunTab);
            app.Track1LampLabel.Text = 'Track #1';
            app.Track1Lamp = uilamp(app.RunTab);
            app.Track2LampLabel = uilabel(app.RunTab);
            app.Track2LampLabel.Text = 'Track #2';
            app.Track2Lamp = uilamp(app.RunTab);
            app.Image7 = uiimage(app.RunTab);
            app.Image7.ImageSource = 'wo.png';
            app.Image8 = uiimage(app.RunTab);
            app.Image8.ImageSource = 'wi.png';
            app.Image12 = uiimage(app.RunTab);
            app.Image12.ImageSource = 'noG.png';
            app.Image13 = uiimage(app.RunTab);
            app.Image13.ImageSource = 'playx.png';
            app.Image14 = uiimage(app.RunTab);
            app.Image14.ImageSource = 'stopx.png';
            app.Image15 = uiimage(app.RunTab);
            app.Image15.ImageSource = 'mixx.png';
            app.Image16 = uiimage(app.RunTab);
            app.Image16.ImageSource = 'exitx.png';
            app.Image17 = uiimage(app.RunTab);
            app.Image17.ImageSource = 'vol.png';
            app.Button = uibutton(app.RunTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Image22 = uiimage(app.RunTab);
            app.Image22.ImageSource = 'fist0.png';
            app.Image23 = uiimage(app.RunTab);
            app.Image23.ImageSource = 'open0.png';
            app.Image24 = uiimage(app.RunTab);
            app.Image24.ImageSource = 'pinch0.png';
            app.ArmbandButtonGroup = uibuttongroup(app.RunTab);
            app.ArmbandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ArmbandButtonGroupSelectionChanged, true);
            app.ArmbandButtonGroup.Title = 'Armband';
            app.MyoButton = uiradiobutton(app.ArmbandButtonGroup);
            app.MyoButton.Text = 'Myo';
            app.MyoButton.Value = true;
            app.GForceButton = uiradiobutton(app.ArmbandButtonGroup);
            app.GForceButton.Text = 'GForce';
            app.UIAxesBracelet = uiaxes(app.RunTab);
            zlabel(app.UIAxesBracelet, 'Z')
            % Show the figure after all components are created
            app.SoundAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = soundApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoundAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoundAppUIFigure)
        end
    end
end